classdef SoundGen_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure matlab.ui.Figure         % UI Figure
        UIAxes   matlab.ui.control.UIAxes % Signal
        Button   matlab.ui.control.Button % Unfreeze
        Button2  matlab.ui.control.Button % Freeze
        Label    matlab.ui.control.Label  % Fc
        Label2   matlab.ui.control.Label  % Portadora :
        Label3   matlab.ui.control.Label  % RE Counter:
        Label4   matlab.ui.control.Label  % RE
        Label5   matlab.ui.control.Label  % Words received:
        Label6   matlab.ui.control.Label  % Word success rate:
        Label7   matlab.ui.control.Label  % receb
        Label8   matlab.ui.control.Label  % w_success
        Label9   matlab.ui.control.Label  % Bit success rate:
        Label10  matlab.ui.control.Label  % b_success
    end


    properties (Access = private)
        freeze = 0; % Variable that toggles where the display updates or not
    end


    methods (Access = public)
        
        function GUI_plot(app, ssm)
            
            if app.freeze == 0;
            plot(app.UIAxes, ssm);
            end
                        
        end

        function GUI_display_fc(app, fc)
            
            app.Label.Text = fc;
            
        end

        function GUI_RE_counter(app, rec)
            
            app.Label4.Text = rec;
            
            
        end
               
        function GUI_stats(app, receb, word_success, bit_success)


            app.Label7.Text = receb;
            app.Label8.Text = word_success;
            app.Label10.Text = bit_success;
            
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
        end

        % Button2 button pushed function
        function FreezeButtonPushed(app)
            app.freeze = 1;
        end

        % Button button pushed function
        function UnfreezeButtonPushed(app)
            app.freeze = 0;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal');
            xlabel(app.UIAxes, 'Sample Number');
            ylabel(app.UIAxes, 'Bit');
            app.UIAxes.XLim = [0 1000];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [-0.1 1.1];
            app.UIAxes.YLimMode = 'manual';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @UnfreezeButtonPushed);
            app.Button.Text = 'Unfreeze';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @FreezeButtonPushed);
            app.Button2.Text = 'Freeze';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Fc';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Portadora :';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'RE Counter:';
            app.Label4 = uilabel(app.UIFigure);
            app.Label4.Text = 'RE';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'Words received:';
            app.Label6 = uilabel(app.UIFigure);
            app.Label6.Text = 'Word success rate:';
            app.Label7 = uilabel(app.UIFigure);
            app.Label7.Text = 'receb';
            app.Label8 = uilabel(app.UIFigure);
            app.Label8.Text = 'w_success';
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'Bit success rate:';
            app.Label10 = uilabel(app.UIFigure);
            app.Label10.Text = 'b_success';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SoundGen_GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end